classdef Info < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DateDatePicker                  matlab.ui.control.DatePicker
        DateLabel                       matlab.ui.control.Label
        clearallButton                  matlab.ui.control.Button
        FramesEditField                 matlab.ui.control.NumericEditField
        FramesEditFieldLabel            matlab.ui.control.Label
        ExposuresEditField              matlab.ui.control.NumericEditField
        ExposuresEditFieldLabel         matlab.ui.control.Label
        daysinvitroEditField            matlab.ui.control.EditField
        daysinvitroEditFieldLabel       matlab.ui.control.Label
        SigmapxlsEditField              matlab.ui.control.NumericEditField
        SigmapxlsEditFieldLabel         matlab.ui.control.Label
        PixelSizenmEditField            matlab.ui.control.NumericEditField
        PixelSizenmEditFieldLabel       matlab.ui.control.Label
        SAVEButton                      matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        DrugsEditField                  matlab.ui.control.EditField
        DrugsEditFieldLabel             matlab.ui.control.Label
        gainEditField                   matlab.ui.control.NumericEditField
        gainEditFieldLabel              matlab.ui.control.Label
        intensityEditField              matlab.ui.control.NumericEditField
        intensityEditFieldLabel         matlab.ui.control.Label
        Master9StuffPanel               matlab.ui.container.Panel
        preFrameEditField               matlab.ui.control.NumericEditField
        preFrameEditFieldLabel          matlab.ui.control.Label
        onlyStimFramesCheckBox          matlab.ui.control.CheckBox
        TimeStartStimsEditField         matlab.ui.control.NumericEditField
        TimeStartStimsEditFieldLabel    matlab.ui.control.Label
        tailCheckBox                    matlab.ui.control.CheckBox
        FreqHzEditField                 matlab.ui.control.NumericEditField
        FreqHzLabel                     matlab.ui.control.Label
        NumAPEditField                  matlab.ui.control.NumericEditField
        NumAPEditFieldLabel             matlab.ui.control.Label
        MagLensDropDown                 matlab.ui.control.DropDown
        MagLensDropDownLabel            matlab.ui.control.Label
        CameraDropDown                  matlab.ui.control.DropDown
        CameraDropDownLabel             matlab.ui.control.Label
        lightSourceDropDown             matlab.ui.control.DropDown
        LightSourceLabel                matlab.ui.control.Label
        ExtSolutionDropDown             matlab.ui.control.DropDown
        ExtSolutionDropDownLabel        matlab.ui.control.Label
        PresetStimRecProtocolsDropDown  matlab.ui.control.DropDown
        PresetStimRecProtocolsDropDownLabel  matlab.ui.control.Label
        DataSpinner                     matlab.ui.control.Spinner
        DataSpinnerLabel                matlab.ui.control.Label
        INFOFILELabel                   matlab.ui.control.Label
        ADDButton                       matlab.ui.control.Button
        NotesTextArea                   matlab.ui.control.TextArea
        NotesTextAreaLabel              matlab.ui.control.Label
        ObjectiveDropDown               matlab.ui.control.DropDown
        ObjectiveDropDownLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        fileLoc % Description
        %chunk = struct;
        infoT = struct;
        FileExtension    
        %name %= app.DataSpinner.Value;        
        %date    
        days_in_vitro    
        gain    
        offset    
        filter    
        PixelSize


        FrameStartEstimulation    
        interEstimulation    
        stimTimes    
        stimTimesSec    
        expDescription1    
        expDescription2    
        expDescription3    
        sigma    
        optic    
            
        lightSource    
        OnlyStimFrames    
        descartFrame

        
    end
  
    
    methods (Access = private)
        
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DateDatePicker
        function DateDatePickerValueChanged(app, event)
            value = app.DateDatePicker.Value;
            formatOut = 'mm.dd.yyyy';
            app.fileLoc = datestr(value, formatOut);
            app.UITable.Data = repmat({''},1,3);
        end

        % Value changed function: PresetStimRecProtocolsDropDown
        function PresetStimRecProtocolsDropDownValueChanged(app, event)
            value = app.PresetStimRecProtocolsDropDown.Value;
            if value == '1'
                app.NumAPEditField.Value = 150;
                app.FreqHzEditField.Value = 1;
                app.tailCheckBox.Value = true;                
                app.TimeStartStimsEditField.Value = 0.2;
                app.ExposuresEditField.Value = 0.05;% timeframe
                app.onlyStimFramesCheckBox.Value = true;
                app.preFrameEditField.Value = 4;
                app.FramesEditField.Value = 3250;
                
            end
            if value == '2'
                app.NumAPEditField.Value = 2;
                app.FreqHzEditField.Value = 1/60.4;% 2trains 60.4 s apart;
                app.tailCheckBox.Value = false;                
                app.TimeStartStimsEditField.Value = 0.4;
                app.ExposuresEditField.Value = 0.04;% timeframe
                app.onlyStimFramesCheckBox.Value = false;
                app.preFrameEditField.Value = 0;
                app.FramesEditField.Value = 3030;
            end
            
        end

        % Callback function: ADDButton, UITable
        function ADDButtonPushed(app, event)
       
            if strcmp(computer,'PCWIN64') == 1 % GM, apr 2019, if working in windows
                strHome = 'D:/Gerardo/owncloud/analysis';    
            else
                strHome = '/home/gerard/nextcloud/analysis';
            end
            
            date = strsplit(app.fileLoc,'.');
            year = date(3);            
            strHome = char(strcat(strHome, '/', year));
                
            
            cd (strHome)
            if isfolder(app.fileLoc) == 0   
                mkdir (app.fileLoc);
            end 
            cd (app.fileLoc)
            %fill up chunk
            chunk = struct;
            % NAME         
            name =app.DataSpinner.Value;
            name = ['Data' num2str(name)];
            chunk.name = name;            
            %% DATE
            chunk.date = app.fileLoc;
            chunk.days_in_vitro = app.daysinvitroEditField.Value;
            %% Stim-Rec.Protocol
            %% SOLUTIONS
            solution = str2double(app.ExtSolutionDropDown.Value);            
            chunk.externalSolution = char(app.ExtSolutionDropDown.Items(solution));
            chunk.drugs = char(app.DrugsEditField.Value);
            %% CAM, GAIN, exposure, FIle EXt            
            camval = str2double(app.CameraDropDown.Value);
            chunk.camera = char(app.CameraDropDown.Items(camval));
            if app.CameraDropDown.Value == '1' || app.CameraDropDown.Value == '3'
                chunk.gain = app.gainEditField.Value;
            end
            chunk.timeframe = app.ExposuresEditField.Value; %exposure
            chunk.frames = app.FramesEditField.Value;
            chunk.FileExtension = '.cxd';
            
            if app.CameraDropDown.Value == '3'
                chunk.FileExtension = '.tif';
            end           
                   
            
            %% OPTICS, PIXEL SIZE && SIGMA
            objectiveX = str2double(app.ObjectiveDropDown.Value);            
            chunk.optic = strcat(app.ObjectiveDropDown.Value, ('x, 1.45 NA'));            
            if app.CameraDropDown.Value == '1'
                app.PixelSizenmEditField.Value = 8000/objectiveX; % 8 um, in nm              
            end

            if app.CameraDropDown.Value == '2'
                app.PixelSizenmEditField.Value = 6500/objectiveX; % 6.5 um, in nm
                
            end

            if app.CameraDropDown.Value == '3'
                objectiveX = objectiveX*str2double(app.MagLensDropDown.Value);
                app.PixelSizenmEditField.Value = 13000/objectiveX; % 13 um, in nm
                if str2double(app.MagLensDropDown.Value) > 1
                    chunk.optic = strcat(chunk.optic, (', Mag.Lens ='), (' '), app.MagLensDropDown.Value ,('x'));
                end
            end
            
            app.SigmapxlsEditField.Value = 120/app.PixelSizenmEditField.Value; % in pixels, sigma PSD = 120 nm
            
            chunk.PixelSize = app.PixelSizenmEditField.Value;
            chunk.sigma = app.SigmapxlsEditField.Value;
            
            % read Protocol Features (stim and recording)
            protocolN = str2double(app.PresetStimRecProtocolsDropDown.Value);
            chunk.protocol = char(app.PresetStimRecProtocolsDropDown.Items(protocolN));
            chunk.NumberAP = app.NumAPEditField.Value;
            chunk.Frequency = app.FreqHzEditField.Value;
            if app.tailCheckBox.Value == true
                chunk.tail = app.tailCheckBox.Value;
            end                            
            chunk.timeStartEstimulation = app.TimeStartStimsEditField.Value;            
            chunk.OnlyStimFrames = app.onlyStimFramesCheckBox.Value;
            
            if chunk.OnlyStimFrames == true                
                chunk.FrameStartEstimulation = 1;
                %% generate grid to descart frames:
                chunk.descartFrame = zeros(1,chunk.Frequency/chunk.timeframe);
                chunk.descartFrame(1,app.preFrameEditField.Value) = 1;
                chunk.descartFrame(1,app.preFrameEditField.Value + 1) = 1;
                %%
                chunk.interEstimulation = sum(chunk.descartFrame);
                
            else
                chunk.FrameStartEstimulation = round(chunk.timeStartEstimulation/chunk.timeframe);
                chunk.interEstimulation = 1/(chunk.Frequency*chunk.timeframe);
                
            end
            
            chunk.stimTimes = round (chunk.FrameStartEstimulation:chunk.interEstimulation:(chunk.NumberAP*chunk.interEstimulation));
            chunk.stimTimesSec = chunk.timeStartEstimulation:(1/chunk.Frequency):((chunk.NumberAP-1)*(1/chunk.Frequency)+chunk.timeStartEstimulation);
            
            %% NOTES
            chunk.Notes = char(app.NotesTextArea.Value);                  
            
            %% ADD CHUNK (Data1, 2...)
            app.infoT.(name) = chunk;
            
            %% +1 to Data Number
            app.DataSpinner.Value = app.DataSpinner.Value + 1;
            
            %% TABLE
            % recreate it completelly each time
            data = app.UITable.Data;
            dataNamesUnsorted = fieldnames(app.infoT);
            
            %% sort the Names
            %% taken from https://www.mathworks.com/matlabcentral/answers/43816-how-can-i-sort-a-string-both-alphabetically-and-numerically
            R = cell2mat(regexp(dataNamesUnsorted ,'(?<Name>\D+)(?<Nums>\d+)','names'));
            tmp = sortrows([{R.Name}' num2cell(cellfun(@(x)str2double(x),{R.Nums}'))]);
            dataNames = strcat(tmp(:,1) ,cellfun(@(x) num2str(x), tmp(:,2),'unif',0));
            %%%
            
            for pos = 1:length(fieldnames(app.infoT))
                newRow = {app.infoT.(dataNames{pos}).name app.infoT.(dataNames{pos}).externalSolution app.infoT.(dataNames{pos}).Notes};
                data(pos,:) = newRow;                
            end
            app.UITable.Data = data;
%             pos = length(fieldnames(app.infoT));
%             data = app.UITable.Data;
%             newRow = {chunk.name chunk.externalSolution chunk.Notes};
%             data(pos,:) = newRow;
%             app.UITable.Data = data;            
            
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            info = app.infoT;
            save('info.mat','info')
            
        end

        % Button pushed function: clearallButton
        function clearallButtonPushed(app, event)
         app.UITable.Data = {};
         app.infoT = struct;
         app.DataSpinner.Value = 1;            
              
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ObjectiveDropDownLabel = uilabel(app.UIFigure);
            app.ObjectiveDropDownLabel.Text = 'Objective';
            app.ObjectiveDropDown = uidropdown(app.UIFigure);
            app.ObjectiveDropDown.Items = {'100X', '150X'};
            app.ObjectiveDropDown.ItemsData = {'100', '150', ''};
            app.ObjectiveDropDown.Value = '100';
            app.NotesTextAreaLabel = uilabel(app.UIFigure);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.UIFigure);
            app.NotesTextArea.Value = {'...'};
            app.ADDButton = uibutton(app.UIFigure, 'push');
            app.ADDButton.ButtonPushedFcn = createCallbackFcn(app, @ADDButtonPushed, true);
            app.ADDButton.Text = 'ADD';
            app.INFOFILELabel = uilabel(app.UIFigure);
            app.INFOFILELabel.Text = 'INFO FILE';
            app.DataSpinnerLabel = uilabel(app.UIFigure);
            app.DataSpinnerLabel.Text = 'Data';
            app.DataSpinner = uispinner(app.UIFigure);
            app.DataSpinner.Limits = [1 Inf];
            app.DataSpinner.Value = 1;
            app.PresetStimRecProtocolsDropDownLabel = uilabel(app.UIFigure);
            app.PresetStimRecProtocolsDropDownLabel.Text = 'Preset Stim-Rec. Protocols';
            app.PresetStimRecProtocolsDropDown = uidropdown(app.UIFigure);
            app.PresetStimRecProtocolsDropDown.Items = {'1Hz stim + TAIL continous', 'Bulk Release'};
            app.PresetStimRecProtocolsDropDown.ItemsData = {'1', '2', '', ''};
            app.PresetStimRecProtocolsDropDown.ValueChangedFcn = createCallbackFcn(app, @PresetStimRecProtocolsDropDownValueChanged, true);
            app.PresetStimRecProtocolsDropDown.Value = '1';
            app.ExtSolutionDropDownLabel = uilabel(app.UIFigure);
            app.ExtSolutionDropDownLabel.Text = 'Ext.Solution';
            app.ExtSolutionDropDown = uidropdown(app.UIFigure);
            app.ExtSolutionDropDown.Items = {'2 mM Ca, 1 mM Mg, CONTROL', '2 mM Ca, 1 mM Mg, 1mM TEA', '0.1 mM Ca, 0.5 mM Mg, 3.6 mM Sr2+'};
            app.ExtSolutionDropDown.ItemsData = {'1', '2', '3', '', '', ''};
            app.ExtSolutionDropDown.Value = '1';
            app.LightSourceLabel = uilabel(app.UIFigure);
            app.LightSourceLabel.Text = 'Light Source';
            app.lightSourceDropDown = uidropdown(app.UIFigure);
            app.lightSourceDropDown.Items = {'Xenon Lamp', '475 LED', '488 LASER', '488 LASER, TIRF'};
            app.lightSourceDropDown.ItemsData = {'1', '2', '3', '4'};
            app.lightSourceDropDown.Value = '1';
            app.CameraDropDownLabel = uilabel(app.UIFigure);
            app.CameraDropDownLabel.Text = 'Camera';
            app.CameraDropDown = uidropdown(app.UIFigure);
            app.CameraDropDown.Items = {'EM-CCD, Hamamatsu', 'S-CMOS, ORCA-Flash4.0 V2, Hamamatsu', 'EM-CCD, iXon Life 888, Andor'};
            app.CameraDropDown.ItemsData = {'1', '2', '3'};
            app.CameraDropDown.Value = '1';
            app.MagLensDropDownLabel = uilabel(app.UIFigure);
            app.MagLensDropDownLabel.Text = 'Mag.Lens';
            app.MagLensDropDown = uidropdown(app.UIFigure);
            app.MagLensDropDown.Items = {'1X', '1.6X', '2X'};
            app.MagLensDropDown.ItemsData = {'1', '1.6', '2'};
            app.MagLensDropDown.Value = '1';
            app.Master9StuffPanel = uipanel(app.UIFigure);
            app.Master9StuffPanel.Title = 'Master-9 Stuff';
            app.NumAPEditFieldLabel = uilabel(app.Master9StuffPanel);
            app.NumAPEditFieldLabel.Text = 'Num AP';
            app.NumAPEditField = uieditfield(app.Master9StuffPanel, 'numeric');
            app.NumAPEditField.Value = 150;
            app.FreqHzLabel = uilabel(app.Master9StuffPanel);
            app.FreqHzLabel.Text = 'Freq (Hz)';
            app.FreqHzEditField = uieditfield(app.Master9StuffPanel, 'numeric');
            app.FreqHzEditField.Value = 1;
            app.tailCheckBox = uicheckbox(app.Master9StuffPanel);
            app.tailCheckBox.Text = 'tail';
            app.tailCheckBox.Value = true;
            app.TimeStartStimsEditFieldLabel = uilabel(app.Master9StuffPanel);
            app.TimeStartStimsEditFieldLabel.Text = 'TimeStartStim (s)';
            app.TimeStartStimsEditField = uieditfield(app.Master9StuffPanel, 'numeric');
            app.TimeStartStimsEditField.Value = 0.2;
            app.onlyStimFramesCheckBox = uicheckbox(app.Master9StuffPanel);
            app.onlyStimFramesCheckBox.Text = 'onlyStimFrames';
            app.onlyStimFramesCheckBox.Value = true;
            app.preFrameEditFieldLabel = uilabel(app.Master9StuffPanel);
            app.preFrameEditFieldLabel.Text = 'preFrame';
            app.preFrameEditField = uieditfield(app.Master9StuffPanel, 'numeric');
            app.preFrameEditField.Limits = [0 Inf];
            app.preFrameEditField.Value = 4;
            app.intensityEditFieldLabel = uilabel(app.UIFigure);
            app.intensityEditFieldLabel.Text = 'intensity (%)';
            app.intensityEditField = uieditfield(app.UIFigure, 'numeric');
            app.gainEditFieldLabel = uilabel(app.UIFigure);
            app.gainEditFieldLabel.Text = 'gain';
            app.gainEditField = uieditfield(app.UIFigure, 'numeric');
            app.gainEditField.Value = 1;
            app.DrugsEditFieldLabel = uilabel(app.UIFigure);
            app.DrugsEditFieldLabel.Text = 'Drugs';
            app.DrugsEditField = uieditfield(app.UIFigure, 'text');
            app.DrugsEditField.Value = '10uM CNQX, 50uM APV';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Data'; 'solution'; 'Notes'};
            app.UITable.RowName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'};
            app.UITable.CellEditCallback = createCallbackFcn(app, @ADDButtonPushed, true);
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.PixelSizenmEditFieldLabel = uilabel(app.UIFigure);
            app.PixelSizenmEditFieldLabel.Text = 'Pixel Size (nm)';
            app.PixelSizenmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelSizenmEditField.Editable = 'off';
            app.SigmapxlsEditFieldLabel = uilabel(app.UIFigure);
            app.SigmapxlsEditFieldLabel.Text = 'Sigma (pxls)';
            app.SigmapxlsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmapxlsEditField.Editable = 'off';
            app.daysinvitroEditFieldLabel = uilabel(app.UIFigure);
            app.daysinvitroEditFieldLabel.Text = 'days in vitro';
            app.daysinvitroEditField = uieditfield(app.UIFigure, 'text');
            app.daysinvitroEditField.Value = '23';
            app.ExposuresEditFieldLabel = uilabel(app.UIFigure);
            app.ExposuresEditFieldLabel.Text = 'Exposure (s)';
            app.ExposuresEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExposuresEditField.Value = 0.05;
            app.FramesEditFieldLabel = uilabel(app.UIFigure);
            app.FramesEditFieldLabel.Text = 'Frames';
            app.FramesEditField = uieditfield(app.UIFigure, 'numeric');
            app.FramesEditField.Limits = [0 Inf];
            app.FramesEditField.Value = 3250;
            app.clearallButton = uibutton(app.UIFigure, 'push');
            app.clearallButton.ButtonPushedFcn = createCallbackFcn(app, @clearallButtonPushed, true);
            app.clearallButton.Text = 'clear all!';
            app.DateLabel = uilabel(app.UIFigure);
            app.DateLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.UIFigure);
            app.DateDatePicker.DisplayFormat = 'MM.dd.uuuu';
            app.DateDatePicker.ValueChangedFcn = createCallbackFcn(app, @DateDatePickerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Info

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end